classdef deformable_explorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeformableExplorerUIFigure  matlab.ui.Figure
        AxialAxes                   matlab.ui.control.UIAxes
        AxialSliceLabel             matlab.ui.control.Label
        SpinnerAxial                matlab.ui.control.Spinner
        CoronalSliceLabel           matlab.ui.control.Label
        SpinnerCoronal              matlab.ui.control.Spinner
        SagittalSliceLabel          matlab.ui.control.Label
        SpinnerSagittal             matlab.ui.control.Spinner
        SliceviewButtonGroup        matlab.ui.container.ButtonGroup
        ButtonAxialView             matlab.ui.control.ToggleButton
        ButtonCoronalView           matlab.ui.control.ToggleButton
        ButtonSagittalView          matlab.ui.control.ToggleButton
        SurfaceListListBoxLabel     matlab.ui.control.Label
        SurfaceListListBox          matlab.ui.control.ListBox
        SagittalAxes                matlab.ui.control.UIAxes
        CoronalAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        subjectID % Description
        surfaceV
        surfaceF
        UniqueEdges
        UniqueEdgesJ
        EdgeFaceMatrix
        T2IMG
        T2NII
        ImShowCLims
        surfaceFaceZ
    end
    
    methods (Access = private)
        
        function results = refreshAxialDisplay(app)
            curAxialSlice = app.SpinnerAxial.Value;
            
             %curSliceView = app.SliceviewButtonGroup.SelectedObject.Text;
            curSurfaceIDX = app.SurfaceListListBox.Value;
            
%             app.surfaceEdges = [...
%                 app.surfaceF{app.SurfaceListListBox.Value}(:, 1), app.surfaceF{app.SurfaceListListBox.Value}(:, 2); ...
%                 app.surfaceF{app.SurfaceListListBox.Value}(:, 1), app.surfaceF{app.SurfaceListListBox.Value}(:, 3); ...
%                 app.surfaceF{app.SurfaceListListBox.Value}(:, 2), app.surfaceF{app.SurfaceListListBox.Value}(:, 3)];
%             app.surfaceEdges = sort(app.surfaceEdges, 2);
%             [app.surfaceEdges, I, J] = unique(app.surfaceEdges, 'rows');
%             keyboard;
            
            hold(app.AxialAxes, 'off');
            
            %switch(curSliceView)
             %   case 'Axial'
            imshow(app.T2IMG(:, :, curAxialSlice), app.ImShowCLims, 'Parent', app.AxialAxes);
            set(app.AxialAxes, 'XLimMode', 'Manual', 'YLimMode', 'Manual', 'XTickLabelMode', 'Manual', 'YTickLabelMode', 'Manual', 'XTickMode', 'Manual', 'YTickMode', 'Manual', 'XTick', [], 'YTick', []);
            
            %I = abs(app.surfaceV{curSurfaceIDX}(:, 3) - curAxialSlice) < 0.5;
            results = intersecting_lines(app, app.surfaceV{curSurfaceIDX}, app.surfaceF{curSurfaceIDX}, curAxialSlice, app.surfaceFaceZ.minAxial, app.surfaceFaceZ.maxAxial);
            
            if(~isempty(results.Contours))
                for z = 1:length(results.Contours)
                    L = line(app.AxialAxes, results.Contours{z}(:, 1), size(app.T2IMG, 1) - results.Contours{z}(:, 2) + 2);
                    set(L, 'Color', 'r');
                end
            else
                L = line(app.AxialAxes, results.OutX, size(app.T2IMG, 1) - results.OutY + 2);
                set(L, 'Color', 'r');
            end
            title(app.AxialAxes, '');
            xlabel(app.AxialAxes, '');
            ylabel(app.AxialAxes, '');
            axis(app.AxialAxes, 'off');
            disableDefaultInteractivity(app.AxialAxes);
        end
        
        function results = refreshSagittalDisplay(app)
            curSagittalSlice = app.SpinnerSagittal.Value;
            curSurfaceIDX = app.SurfaceListListBox.Value;
            
            imshow(fliplr(rot90(squeeze(app.T2IMG(:, curSagittalSlice, :)))), app.ImShowCLims, 'Parent', app.SagittalAxes);
            TV = cat(2, ...
                app.surfaceV{curSurfaceIDX}(:, 2), ...    
                size(app.T2IMG, 3) - app.surfaceV{curSurfaceIDX}(:, 3) + 2, ...
                app.surfaceV{curSurfaceIDX}(:, 1));
            
            results = intersecting_lines(app, TV, app.surfaceF{curSurfaceIDX}, curSagittalSlice, app.surfaceFaceZ.minSagittal, app.surfaceFaceZ.maxSagittal);
            if(~isempty(results.Contours))
                for z = 1:length(results.Contours)
                    L = line(app.SagittalAxes, results.Contours{z}(:, 1), results.Contours{z}(:, 2));
                    set(L, 'Color', 'r');
                end    
            else
                L = line(app.SagittalAxes, results.OutX, results.OutY);
                set(L, 'Color', 'r');
            end
            title(app.SagittalAxes, '');
            xlabel(app.SagittalAxes, '');
            ylabel(app.SagittalAxes, '');
            axis(app.SagittalAxes, 'off');
            disableDefaultInteractivity(app.SagittalAxes);
        end
        
        function results = refreshCoronalDisplay(app)
            curCoronalSlice = app.SpinnerCoronal.Value;
            curSurfaceIDX = app.SurfaceListListBox.Value;
            
            imshow(rot90(squeeze(app.T2IMG(curCoronalSlice, :, :))), app.ImShowCLims, 'Parent', app.CoronalAxes);
            TV = cat(2, ...
                app.surfaceV{curSurfaceIDX}(:, 1), ...
                app.surfaceV{curSurfaceIDX}(:, 3), ...
                size(app.T2IMG, 1) - app.surfaceV{curSurfaceIDX}(:, 2) + 2);
            results = intersecting_lines(app, TV, app.surfaceF{curSurfaceIDX}, curCoronalSlice, app.surfaceFaceZ.minCoronal, app.surfaceFaceZ.maxCoronal);
            %hold(app.AxialAxes, 'on');
            if(~isempty(results.Contours))
                for z = 1:length(results.Contours)
                    L = line(app.CoronalAxes, results.Contours{z}(:, 1), size(app.T2IMG, 3) - results.Contours{z}(:, 2) + 2);
                    set(L, 'Color', 'r');
                end
            else
                L = line(app.CoronalAxes, results.OutX, size(app.T2IMG, 3) - results.OutY + 2);
                set(L, 'Color', 'r');
            end

            title(app.CoronalAxes, '');
            xlabel(app.CoronalAxes, '');
            ylabel(app.CoronalAxes, '');
            axis(app.CoronalAxes, 'off');
                        
            disableDefaultInteractivity(app.CoronalAxes);
            set(app.CoronalAxes, 'HitTest', 'off');
                
        end
        
        function results = intersecting_lines(app, V, F, L, MinZ, MaxZ)
            % finds the intersecting lines for the surface (V, F) at level
            % L on the 3rd dimension of V, that is the Z level
            try
                [results.Contours, ~, ~] = new_surface_contour_precomputed(V, F, L, app.UniqueEdges, app.UniqueEdgesJ, app.EdgeFaceMatrix);
            catch ME
                disp('contour problem, using slower method');
                results.Contours = [];
            end
            Z = cat(2, V(F(:, 1), 3), V(F(:, 2), 3), V(F(:, 3), 3));
            
            MaxZ = max(Z, [], 2);
            MinZ = min(Z, [], 2);
  
            results.IntersectingFacesMask = MaxZ > L & MinZ < L;
            
            EdgeX = cat(3, ...
                [V(F(results.IntersectingFacesMask, 1), 1), V(F(results.IntersectingFacesMask, 2), 1)], ...
                [V(F(results.IntersectingFacesMask, 1), 1), V(F(results.IntersectingFacesMask, 3), 1)], ...
                [V(F(results.IntersectingFacesMask, 2), 1), V(F(results.IntersectingFacesMask, 3), 1)]);
            
            EdgeY = cat(3, ...
                [V(F(results.IntersectingFacesMask, 1), 2), V(F(results.IntersectingFacesMask, 2), 2)], ...
                [V(F(results.IntersectingFacesMask, 1), 2), V(F(results.IntersectingFacesMask, 3), 2)], ...
                [V(F(results.IntersectingFacesMask, 2), 2), V(F(results.IntersectingFacesMask, 3), 2)]);
            
            EdgeZ = cat(3, ...
                [V(F(results.IntersectingFacesMask, 1), 3), V(F(results.IntersectingFacesMask, 2), 3)], ...
                [V(F(results.IntersectingFacesMask, 1), 3), V(F(results.IntersectingFacesMask, 3), 3)], ...
                [V(F(results.IntersectingFacesMask, 2), 3), V(F(results.IntersectingFacesMask, 3), 3)]);
            
            [EdgeZ, EdgeZSortIDX] = sort(EdgeZ, 2);
            
            for z = 1:size(EdgeZSortIDX, 3)
                RowsToSwap = EdgeZSortIDX(:, 1, z) == 2;
                if any(RowsToSwap)
                    EdgeX(RowsToSwap, :, z) = EdgeX(RowsToSwap, [2, 1], z);
                    EdgeY(RowsToSwap, :, z) = EdgeY(RowsToSwap, [2, 1], z);
                end
            end
			
			I = (EdgeZ == L);
			
			if any(I, 'all')
				%disp('yes');
				EdgeZ(I) = L + 1e-3;
			end
			clear I;
			
            IntersectingEdgesMask = squeeze(EdgeZ(:, 1, :) < L & EdgeZ(:, 2, :) > L);
			
            S = (sum(IntersectingEdgesMask, 2) == 2);
            if ~all(S)
				%keyboard;
                IntersectingEdgesMask = IntersectingEdgesMask(S, :);
                EdgeX = EdgeX(S, :, :);
                EdgeY = EdgeY(S, :, :);
                EdgeZ = EdgeZ(S, :, :);
            end
            
%             EdgeTZ = EdgeZ;
%             for z = 1:size(EdgeZ, 1)
%                 %EdgeI(z, :) = find(IntersectingEdgesIDX(z, :));
%                 % move the valid edges to the first two positions
%                 EdgeZ(z, :, 1:2) = EdgeZ(z, :, IntersectingEdgesMask(z, :));
%                 EdgeX(z, :, 1:2) = EdgeX(z, :, IntersectingEdgesMask(z, :));
%                 EdgeY(z, :, 1:2) = EdgeY(z, :, IntersectingEdgesMask(z, :));
%             end
%             EdgeZ = EdgeZ(:, :, 1:2);
%             EdgeX = EdgeX(:, :, 1:2);
%             EdgeY = EdgeY(:, :, 1:2);
            [SliceI, RowI] = find(IntersectingEdgesMask');
            
            RowI = reshape(RowI, 2, numel(RowI) / 2);
            RowI = reshape(RowI', numel(RowI) / 2, 1, 2);
            SliceI = reshape(SliceI, 2, numel(SliceI) / 2);
            SliceI = reshape(SliceI', numel(SliceI) / 2, 1, 2);
            RowI = repmat(RowI, [1, 2, 1]);
            SliceI = repmat(SliceI, [1, 2, 1]);
            ColI = repmat(1:2, size(RowI, 1), 1, 2);
            
            %ExtractI = sub2ind(size(EdgeTZ), RowI, ColI, SliceI);
            SZ = int32(size(EdgeZ));
            RowI = int32(RowI);
            SliceI = int32(SliceI);
            ColI = int32(ColI);
            
            ExtractI = RowI + (ColI - 1) .* SZ(1) + (SliceI - 1) .* (SZ(1) .* SZ(2));
            EdgeZ = EdgeZ(ExtractI);
            EdgeX = EdgeX(ExtractI);
            EdgeY = EdgeY(ExtractI);
            
            EdgeZFrac = (L - EdgeZ(:, 1, :)) ./ (EdgeZ(:, 2, :) - EdgeZ(:, 1, :));
            
            results.OutX = squeeze((1 - EdgeZFrac) .* EdgeX(:, 1, :) + EdgeZFrac .* EdgeX(:, 2, :))';
            results.OutY = squeeze((1 - EdgeZFrac) .* EdgeY(:, 1, :) + EdgeZFrac .* EdgeY(:, 2, :))';
%             % reorder OutX
%             %results.OutC = cell(1, size(results.OutX, 2));
%             %curC = 1;
%             %curI = 1;
%             
            %T = [results.OutX(:), results.OutY(:)];
            %[results.Contours, ~, ~] = new_surface_contour(V, F, L);
            
            %keyboard;
            %notAddedYet = true(1, size(results.OutX, 2));
            %while any(notAddedYet)
            %    firstI = find(notAddedYet, 1, 'first');
                
            %end
            % reorder OutX
            %keyboard;
            %SecondVertex
            %OutX = (1 - EdgeZFrac) * + EdgeZFrac * 
            %keyboard;
        end
        
        function results = update_surfaceFaceZ(app, V, F)
            axialZ = V(:, 3);
            VZ = cat(2, axialZ(F(:, 1)), axialZ(F(:, 2)), axialZ(F(:, 3)));
            app.surfaceFaceZ.maxAxial = max(VZ, [], 2);
            app.surfaceFaceZ.minAxial = min(VZ, [], 2);
            clear axialZ;
            
            sagittalZ = V(:, 1);
            VZ = cat(2, sagittalZ(F(:, 1)), sagittalZ(F(:, 2)), sagittalZ(F(:, 3)));
            app.surfaceFaceZ.maxSagittal = max(VZ, [], 2);
            app.surfaceFaceZ.minSagittal = min(VZ, [], 2);
            clear sagittalZ;
            
            coronalZ = size(app.T2IMG, 1) - V(:, 2) + 2;
            VZ = cat(2, coronalZ(F(:, 1)), coronalZ(F(:, 2)), coronalZ(F(:, 3)));
            app.surfaceFaceZ.maxCoronal = max(VZ, [], 2);
            app.surfaceFaceZ.minCoronal = min(VZ, [], 2);
            clear coronalZ;
            
            % make the UniqueEdges, app.UniqueEdges, app.UniqueEdgesJ, app.EdgeFaceMatrix
            SortedF = sort(F, 2);

            Edges = [SortedF(:, 1) SortedF(:, 2); SortedF(:, 2) SortedF(:, 3); SortedF(:, 1) SortedF(:, 3)];
            
            FaceIndices = repmat((1:size(F, 1))', 3, 1);
            
            %[SortedEdges, EdgesI] = sortrows(Edges, [1 2]);
            %SortedFaceIndices = FaceIndices(EdgesI);
            
            [app.UniqueEdges, ~, app.UniqueEdgesJ] = unique(Edges, 'rows');
            %SZ = [I(1); diff(I, 1, 1)];
            
            %StartIndices = [1; I(1:end - 1) + 1];
            
            %G = zeros(size(UniqueEdges, 1), 1);
            %G(StartIndices) = 1;
            %G = cumsum(G);
            
            app.EdgeFaceMatrix = sparse(FaceIndices, app.UniqueEdgesJ, 1);
            

        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, subjectID)
            app.subjectID = subjectID;
            %set(app.DeformableExplorerUIFigure, 'RendererMode', 'Manual')
            % look for the files
            
            T2FileName = fullfile('RawT2RadiologicalIsotropic', [app.subjectID '.nii.gz']);
            if exist(T2FileName, 'file') ~= 2
                error("T2 image not found");
            else
                [app.T2NII, app.T2IMG] = load_nii(T2FileName);
                %size(app.T2IMG)
                
                % calculate the 5th and 95th percentiles
                C = sort(app.T2IMG(app.T2IMG > 0));
                
                app.ImShowCLims = double([C(round(numel(C) / 20)), C(round(numel(C) * 19 / 20))]);
                
                app.SpinnerAxial.Value = floor(size(app.T2IMG, 3) / 2);
                app.SpinnerAxial.Limits = [1, size(app.T2IMG, 3)];
                app.SpinnerCoronal.Value = floor(size(app.T2IMG, 1) / 2);
                app.SpinnerCoronal.Limits = [1, size(app.T2IMG, 1)];
                app.SpinnerSagittal.Value = floor(size(app.T2IMG, 2) / 2);
                app.SpinnerSagittal.Value = 218;
                app.SpinnerSagittal.Limits = [1, size(app.T2IMG, 2)];
                
                D = dir(fullfile('SurfReconDeformable', app.subjectID, '**', '/*.vtp'));
                if(isempty(D))
                    warning("no surfaces found");
                else
                    [DN{1:length(D)}] = D.name;
                    [DD{1:length(D)}] = D.datenum;
                    [DF{1:length(D)}] = D.folder;
                    surfaceList = cellfun(@(x, y) (fullfile(x, y)), DF, DN, 'UniformOutput', false);
                    
                    DD = cat(1, DD{:});
                    
                    [~, I] = sort(DD);
                    
                    surfaceList = strrep(surfaceList(I), '.vtp', '_tkr.surf');
                    
                    DN = DN(I);
                    app.SurfaceListListBox.Items = DN(:)';
                    app.SurfaceListListBox.ItemsData = 1:length(DN);
                    app.SurfaceListListBox.Value = length(DN);
                    app.SurfaceListListBox.Value = 1;
                    
                    app.surfaceV = cell(1, length(DN));
                    app.surfaceF = cell(1, length(DN));
                    %InvSForm = inv(app.T2NII.hdr.sform);
                    voxres = app.T2NII.hdr.dime.pixdim(2:4);
                    voldim = [size(app.T2IMG, 2), size(app.T2IMG, 1), size(app.T2IMG, 3)];
                    T = freesurfer_tkr2vox(voxres, voldim);
                    % VoxV = TKRV * T(1:3, 1:3)' + T(1:3, 4)';
                    for z = 1:length(surfaceList)
                        [app.surfaceV{z}, app.surfaceF{z}] = freesurfer_read_surf(surfaceList{z});
                        app.surfaceV{z} = app.surfaceV{z} * T(1:3, 1:3)' + T(1:3, 4)' + 1;
                        %min(app.surfaceV{z})
                        %max(app.surfaceV{z})
                    end
                    %disp(app.surfaceList');
                end
                update_surfaceFaceZ(app, app.surfaceV{app.SurfaceListListBox.Value}, app.surfaceF{app.SurfaceListListBox.Value});
                
                refreshAxialDisplay(app);
                refreshCoronalDisplay(app);
                refreshSagittalDisplay(app);
                axis(app.SagittalAxes, 'off');
                axis(app.CoronalAxes, 'off');
                axis(app.AxialAxes, 'off');

               
            end
        end

        % Value changed function: SpinnerSagittal
        function SpinnerSagittalValueChanged(app, event)
            value = app.SpinnerSagittal.Value;
            %curSliceView = app.SliceviewButtonGroup.SelectedObject.Text;
            
            %if strcmp(curSliceView, 'Sagittal')
                refreshSagittalDisplay(app);
            %end
        end

        % Value changed function: SpinnerCoronal
        function SpinnerCoronalValueChanged(app, event)
            value = app.SpinnerCoronal.Value;
            
            %curSliceView = app.SliceviewButtonGroup.SelectedObject.Text;
            
            %if strcmp(curSliceView, 'Coronal')
                refreshCoronalDisplay(app);
            %end
        end

        % Value changed function: SpinnerAxial
        function SpinnerAxialValueChanged(app, event)
            value = app.SpinnerAxial.Value;
            
            %curSliceView = app.SliceviewButtonGroup.SelectedObject.Text;
            
            %if strcmp(curSliceView, 'Axial')
                refreshAxialDisplay(app);
            %end
        end

        % Selection changed function: SliceviewButtonGroup
        function SliceviewButtonGroupSelectionChanged(app, event)
            selectedButton = app.SliceviewButtonGroup.SelectedObject;
            %refreshAxialDisplay(app);
            
        end

        % Value changed function: SurfaceListListBox
        function SurfaceListListBoxValueChanged(app, event)
            value = app.SurfaceListListBox.Value;
      

            update_surfaceFaceZ(app, app.surfaceV{app.SurfaceListListBox.Value}, app.surfaceF{app.SurfaceListListBox.Value});
            refreshAxialDisplay(app);
            refreshCoronalDisplay(app);
            refreshSagittalDisplay(app);
        end

        % Button down function: DeformableExplorerUIFigure
        function DeformableExplorerUIFigureButtonDown(app, event)
            event
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeformableExplorerUIFigure = uifigure('Visible', 'off');
            app.DeformableExplorerUIFigure.Name = 'Deformable Explorer';
            app.DeformableExplorerUIFigure.ButtonDownFcn = createCallbackFcn(app, @DeformableExplorerUIFigureButtonDown, true);
            app.AxialAxes = uiaxes(app.DeformableExplorerUIFigure);
            title(app.AxialAxes, 'Title')
            xlabel(app.AxialAxes, 'X')
            ylabel(app.AxialAxes, 'Y')
            app.AxialSliceLabel = uilabel(app.DeformableExplorerUIFigure);
            app.AxialSliceLabel.Text = 'Axial Slice';
            app.SpinnerAxial = uispinner(app.DeformableExplorerUIFigure);
            app.SpinnerAxial.ValueChangedFcn = createCallbackFcn(app, @SpinnerAxialValueChanged, true);
            app.CoronalSliceLabel = uilabel(app.DeformableExplorerUIFigure);
            app.CoronalSliceLabel.Text = 'Coronal Slice';
            app.SpinnerCoronal = uispinner(app.DeformableExplorerUIFigure);
            app.SpinnerCoronal.ValueChangedFcn = createCallbackFcn(app, @SpinnerCoronalValueChanged, true);
            app.SagittalSliceLabel = uilabel(app.DeformableExplorerUIFigure);
            app.SagittalSliceLabel.Text = 'Sagittal Slice';
            app.SpinnerSagittal = uispinner(app.DeformableExplorerUIFigure);
            app.SpinnerSagittal.ValueChangedFcn = createCallbackFcn(app, @SpinnerSagittalValueChanged, true);
            app.SliceviewButtonGroup = uibuttongroup(app.DeformableExplorerUIFigure);
            app.SliceviewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SliceviewButtonGroupSelectionChanged, true);
            app.SliceviewButtonGroup.Title = 'Slice view';
            app.ButtonAxialView = uitogglebutton(app.SliceviewButtonGroup);
            app.ButtonAxialView.Text = 'Axial';
            app.ButtonAxialView.Value = true;
            app.ButtonCoronalView = uitogglebutton(app.SliceviewButtonGroup);
            app.ButtonCoronalView.Text = 'Coronal';
            app.ButtonSagittalView = uitogglebutton(app.SliceviewButtonGroup);
            app.ButtonSagittalView.Text = 'Sagittal';
            app.SurfaceListListBoxLabel = uilabel(app.DeformableExplorerUIFigure);
            app.SurfaceListListBoxLabel.Text = 'Surface List';
            app.SurfaceListListBox = uilistbox(app.DeformableExplorerUIFigure);
            app.SurfaceListListBox.ValueChangedFcn = createCallbackFcn(app, @SurfaceListListBoxValueChanged, true);
            app.SagittalAxes = uiaxes(app.DeformableExplorerUIFigure);
            title(app.SagittalAxes, 'Title')
            xlabel(app.SagittalAxes, 'X')
            ylabel(app.SagittalAxes, 'Y')
            app.CoronalAxes = uiaxes(app.DeformableExplorerUIFigure);
            title(app.CoronalAxes, 'Title')
            xlabel(app.CoronalAxes, 'X')
            ylabel(app.CoronalAxes, 'Y')
            % Show the figure after all components are created
            app.DeformableExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = deformable_explorer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DeformableExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeformableExplorerUIFigure)
        end
    end
end